classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        SignalInfoTextArea              matlab.ui.control.TextArea
        SignalInfoTextAreaLabel         matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        InformacionLabel                matlab.ui.control.Label
        SealesDisponiblesDropDown       matlab.ui.control.DropDown
        SealesDisponiblesDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

        end

        % Value changed function: AmplitudeEditField, 
        % FrequencyEditField, PhaseEditField, 
        % SealesDisponiblesDropDown
        function SealesDisponiblesDropDownValueChanged(app, event)
            value = app.SealesDisponiblesDropDown.Value;
            disp(value);


        end

        % Callback function
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            disp(value);
        end

        % Callback function
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            disp(value);
        end

        % Callback function
        function PhaseEditFieldValueChanged(app, event)
            value = app.PhaseEditField.Value;
            disp(value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            disp("Pushed");
            
            value = app.SealesDisponiblesDropDown.Value;
            amplitude = app.FrequencyEditField.Value;
            frequency = app.FrequencyEditField.Value;
            phase =  app.PhaseEditField.Value;


            switch (value)
                case "Imagen Digital"
                    hold(app.UIAxes,"off");
                    title(app.UIAxes,value);
                    a = imread("peppers.png");
                    histogram(app.UIAxes,a);
                    app.SignalInfoTextArea.Value = "Histograma de una imagen a color. Este tipo de imagenes, una imagen a color f(x,y,c) devuelve la intensidad en el punto x,y del canal c";
                case "Contador Binario"
                    hold(app.UIAxes,'on');
                    title(app.UIAxes,value);
                    
                    % Bit 0
                    t = linspace(0,100);
                    sq = amplitude*square(4*frequency*t+phase);
                    plot(app.UIAxes, sq, "r")

                    % Bit 1
                    sq = amplitude*square(2*frequency*t*2*phase) + amplitude;
                    plot(app.UIAxes, sq, 'b')

                    % Bit 2
                    sq = amplitude*square(frequency*t) + 2*amplitude;
                    plot(app.UIAxes, sq, 'g')
                    
                    app.SignalInfoTextArea.Value = "Salidas de un contador binario de 3 bits representado por multiples ondas cuadradas";
                    hold(app.UIAxes,'off');
                case "Orbita Terrestre"
                    hold(app.UIAxes,"off");
                    title(app.UIAxes,value);
                    x = linspace(-50,50);
                    y = amplitude*sin(frequency*x+phase);
                    plot(app.UIAxes, y, 'LineWidth',1);
                    app.SignalInfoTextArea.Value = "Distancia entre el sol y la tierra a lo largo de los años";
                    hold(app.UIAxes,"off");
                case "Señal de Reloj Digital"
                    title(app.UIAxes,value);
                    t = 0:0.001:1;
                    sq = amplitude*square(frequency*t+phase);
                    plot(app.UIAxes, sq, 'LineWidth', 1);
                    hold(app.UIAxes,"off");
                    app.SignalInfoTextArea.Value = "Señal de onda cuadrada utilizada como pulso de reloj";
                case "Medicion de Temperatura"
                    title(app.UIAxes,value);
                    [X,Y] = meshgrid(1:0.5:10,1:20);
                    Z =amplitude*Y.*sin(frequency*X + phase) - amplitude*X.*cos(frequency*Y + phase);
                    surf(app.UIAxes,X,Y,Z);
                    app.SignalInfoTextArea.Value = "La medcion de la temperatura en una superficie varia bastante de acuerdo al punto en el que nos encontremos";
                    hold(app.UIAxes,"off");
                case "Sismografo"
                    title(app.UIAxes,value);
                    t= -0.5:.001:.5;
                    x11=rand(size(t));
                    plot(app.UIAxes,x11,'LineWidth',2);
                    app.SignalInfoTextArea.Value = "Variaciones aleatorias de un sismografo";
                    hold(app.UIAxes,"off");
                case "Corriente Alterna"
                    title(app.UIAxes,value);
                    x = linspace(0,20);
                    y = amplitude* sin(frequency*x + phase);
                    app.SignalInfoTextArea.Value = "La corriente alterna siempre varia entre positivo y negativo, y lo hace de forma determinista";
                    plot(app.UIAxes,y);
                    hold(app.UIAxes,"off");
                case "Pulso Cardiaco"
                    title(app.UIAxes,value);
                    t= -0.5:.001:.5;
                    x11 = amplitude + sin(frequency*t + phase);
                    x10=amplitude * rand(size(t));
                    x12 = x11 + x10;
                    plot(app.UIAxes,x12,'LineWidth',2);
                    app.SignalInfoTextArea.Value = "El pulso cardiaco es normalmente determinista aunque situaciones especiales pueden alterarlo";
                    hold(app.UIAxes,"off");
                case "Altura de una pelota al Rebota"
                    title(app.UIAxes,value);
                    t = linspace(0,10);
                    x1 = abs(amplitude * sin(frequency*t).*(t-10));
                    plot(app.UIAxes,real(x1));
                    app.SignalInfoTextArea.Value = "La altitud de una pelota disminuye de manera inversamente proporcional a su elasticidad";
                    hold(app.UIAxes,"off");
                case "Crecimiento de infectados en una pandemia"
                    title(app.UIAxes,value);                    
                    t = linspace(0,10);
                    x1 = exp(t-phase);
                    plot(app.UIAxes,real(x1));                    app.SignalInfoTextArea.Value = "La mayoria de pandemias mal controladas siempre presentaran un aumento de infectados respecto al tiempo exponencial";
                    hold(app.UIAxes,"off");
                otherwise
                    disp("c");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = 'logo.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señales')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SealesDisponiblesDropDownLabel = uilabel(app.UIFigure);
            app.SealesDisponiblesDropDownLabel.Text = 'Señales Disponibles';
            app.SealesDisponiblesDropDown = uidropdown(app.UIFigure);
            app.SealesDisponiblesDropDown.Items = {'Imagen Digital', 'Crecimiento de infectados en una pandemia', 'Contador Binario', 'Orbita Terrestre', 'Señal de Reloj Digital', 'Medicion de Temperatura', 'Sismografo', 'Corriente Alterna', 'Pulso Cardiaco', 'Altura de una pelota al Rebota'};
            app.SealesDisponiblesDropDown.ValueChangedFcn = createCallbackFcn(app, @SealesDisponiblesDropDownValueChanged, true);
            app.SealesDisponiblesDropDown.Value = 'Imagen Digital';
            app.InformacionLabel = uilabel(app.UIFigure);
            app.InformacionLabel.Text = 'Informacion';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @SealesDisponiblesDropDownValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SealesDisponiblesDropDownValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @SealesDisponiblesDropDownValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalInfoTextAreaLabel = uilabel(app.UIFigure);
            app.SignalInfoTextAreaLabel.Text = 'Signal Info';
            app.SignalInfoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end